classdef diffusion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        ControlTab                     matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout11                   matlab.ui.container.GridLayout
        initialthresholdSpinner        matlab.ui.control.Spinner
        initialthresholdSpinnerLabel   matlab.ui.control.Label
        ProcessDataButton              matlab.ui.control.Button
        loadimagesButton               matlab.ui.control.Button
        GridLayout10                   matlab.ui.container.GridLayout
        SetsavelocationButton          matlab.ui.control.Button
        saveLocation                   matlab.ui.control.Label
        saveDataButton                 matlab.ui.control.Button
        extractDataButton              matlab.ui.control.Button
        GridLayout6                    matlab.ui.container.GridLayout
        finishMButton                  matlab.ui.control.Button
        MaskButton                     matlab.ui.control.Button
        MaskROILabel                   matlab.ui.control.Label
        getfinalgraphsButton           matlab.ui.control.Button
        SettingsTab                    matlab.ui.container.Tab
        GridLayout9                    matlab.ui.container.GridLayout
        GridLayout5                    matlab.ui.container.GridLayout
        CroptoROILabel                 matlab.ui.control.Label
        finishCButton                  matlab.ui.control.Button
        CropButton                     matlab.ui.control.Button
        ExtractslopeButton             matlab.ui.control.Button
        GridLayout4                    matlab.ui.container.GridLayout
        colormapDropDown               matlab.ui.control.DropDown
        colormapDropDownLabel          matlab.ui.control.Label
        edgethreshholdEditField        matlab.ui.control.NumericEditField
        edgethreshholdEditFieldLabel   matlab.ui.control.Label
        smoothingfactorEditField       matlab.ui.control.NumericEditField
        smoothingfactorEditFieldLabel  matlab.ui.control.Label
        GridLayout7                    matlab.ui.container.GridLayout
        ClearButton                    matlab.ui.control.Button
        GridLayout8                    matlab.ui.container.GridLayout
        cErrEditField                  matlab.ui.control.NumericEditField
        cErrEditFieldLabel             matlab.ui.control.Label
        ErrEditField                   matlab.ui.control.NumericEditField
        ErrEditFieldLabel              matlab.ui.control.Label
        convEditField                  matlab.ui.control.NumericEditField
        convEditFieldLabel             matlab.ui.control.Label
        wEditField                     matlab.ui.control.NumericEditField
        wEditFieldLabel                matlab.ui.control.Label
        mErrEditField                  matlab.ui.control.NumericEditField
        mErrEditFieldLabel             matlab.ui.control.Label
        BEditField                     matlab.ui.control.NumericEditField
        BEditField_2Label              matlab.ui.control.Label
        mEditField                     matlab.ui.control.NumericEditField
        mEditFieldLabel                matlab.ui.control.Label
        conversionparameterscmpxLabel  matlab.ui.control.Label
        AEditField                     matlab.ui.control.NumericEditField
        AEditFieldLabel                matlab.ui.control.Label
        FilesToProcess                 matlab.ui.control.ListBox
        GridLayout3                    matlab.ui.container.GridLayout
        Rsquare                        matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        ImageDisplay                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImageOriginal % the original of the currently processed image
        ImageItem % the item number of the image
        CurrentImage % the image shown in the viewport
        CropRegion % the area to focus on
        ROI % the region of intest marked by the rectangle
        Data % two column matrix. first columnt x index, second y.
        ProcessedData % table of final x,y and errors
        
    end
    
    methods (Access = private)
        
        function success = smoothAndFilter(app)
            
            switch app.colormapDropDown.Value
                    case "R"
                        app.CurrentImage = app.ImageOriginal(:,:,1);
                    case "G"
                        app.CurrentImage = app.ImageOriginal(:,:,2);
                    case "B"
                        app.CurrentImage = app.ImageOriginal(:,:,1);
                    case "WB"
                        app.CurrentImage = rgb2gray(app.ImageOriginal);
                    otherwise
                        error('Colormap Faild for some reason. -zecharia');
            end

            if ~isnan(app.CropRegion)
                app.CurrentImage = app.CurrentImage( ...
                    ceil(app.CropRegion(2)):floor(app.CropRegion(2)+app.CropRegion(4)), ...
                    ceil(app.CropRegion(1)):floor(app.CropRegion(1)+app.CropRegion(3)));
            end  
            app.CurrentImage = app.smoothImage(app.CurrentImage, app.smoothingfactorEditField.Value);
            app.CurrentImage = uint8(double(app.CurrentImage).*(app.CurrentImage > app.initialthresholdSpinner.Value));
            
            app.CurrentImage = uint8(255*(rangefilt(app.CurrentImage) > app.edgethreshholdEditField.Value));
            imshow(app.CurrentImage, 'Parent',app.ImageDisplay);
            axis(app.ImageDisplay, 'tight');

            success = true;
        end
        % 
        function imageOut = smoothImage(app, imageIn,N)
            % Smooths 2D grayscale Image.
            % 
            %
            %function imageOut = smoothImage(app, imageIn, N)
            % 
            % This function smooths the data in matrixIn using a mean filter over a
            % rectangle of size (2*N+1)-by-(2*N+1).  Basically, you end up replacing
            % element "i" by the mean of the rectange centered on "i". At the edges of the matrix,
            % where you cannot build a full rectangle, as much of the rectangle that
            % fits on your matrix is used (similar to the default on Matlab's builtin
            % function "smooth").
            % 
            % "imageIn": original matrix
            % "N": number of points used to smooth.
            % "imageOut": smoothed Image.
            % 
            %   Adapted from smooth2a by Zecharia Reisler, May 2024.
            %   Chemistry Division, HUJI. 
            %   
            %   smooth2a:
            % 	Written by Greg Reeves, March 2009.
            % 	Division of Biology
            % 	Caltech
            % 
            % 	Inspired by "smooth2", written by Kelly Hilands, October 2004
            % 	Applied Research Laboratory
            % 	Penn State University
            % 
            % 	Developed from code written by Olof Liungman, 1997
            % 	Dept. of Oceanography, Earth Sciences Centre
            % 	G�teborg University, Sweden
            % 	E-mail: olof.liungman@oce.gu.se
            %
            % Convert the image from a uint8 to a double matrix, so that
            % matrix multiplication actions can take place.
            %
            imageIn = double(imageIn);
            %
            % Building matrices that will compute running sums.  The left-matrix, eL,
            % smooths along the rows.  The right-matrix, eR, smooths along the
            % columns.  You end up replacing element "i" by the mean of a (2*N+1)-by- 
            % (2*N+1) rectangle centered on element "i".
            %
            [row,col] = size(imageIn);
            eL = spdiags(ones(row,2*N+1),(-N:N),row,row);
            eR = spdiags(ones(col,2*N+1),(-N:N),col,col);
            %
            % For each element, we have to count how many elements went into
            % the sums.  This is so we can divide by that number to get a mean.  We use
            % the same matrices to do this (ie, "eL" and "eR").
            %
            nrmlize = eL*ones(row,col)*eR;
            %
            % Actually taking the mean.
            %
            imageOut = eL*imageIn*eR;
            imageOut = imageOut./nrmlize;
            %
            % convert imageOut to uint8 matrix so it will be an image.
            %
            imageOut = uint8(round(imageOut));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadimagesButton
        function loadimagesButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            [file, location] = uigetfile({'*.jpeg; *.png; *.jpg; *.bmp'},"MultiSelect",'on');
            app.UIFigure.Visible = 'on';
            if ~isnumeric(file)
                if ischar(file)
                    file = {file};
                end
                for i = 1:length(file)
                    if ~(any(strcmp(app.FilesToProcess.Items, file(i))))
                        app.FilesToProcess.Items(end+1) = file(i);
                        app.FilesToProcess.ItemsData(end+1) = {location};
                    end
                end
            end
        end

        % Double-clicked callback: FilesToProcess
        function FilesToProcessDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            if item
                app.ImageItem = item;
                app.ImageOriginal = imread([char(app.FilesToProcess.ItemsData(item+1)) ...
                    char(app.FilesToProcess.Items(item))]);
                app.smoothAndFilter();
                
                app.Data = [];
                app.ProcessedData = [];
                cla(app.UIAxes);
                app.Rsquare.Text = '';
                app.ProcessDataButton.Enable = 'off';
            end
        end

        % Value changed function: smoothingfactorEditField
        function smoothingfactorEditFieldValueChanged(app, event)
            value = app.smoothingfactorEditField.Value;
            app.smoothAndFilter();
        end

        % Value changed function: edgethreshholdEditField
        function edgethreshholdEditFieldValueChanged(app, event)
            value = app.edgethreshholdEditField.Value;
            app.smoothAndFilter();
        end

        % Value changed function: colormapDropDown
        function colormapDropDownValueChanged(app, event)
            value = app.colormapDropDown.Value;
            app.smoothAndFilter();
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            if isempty(app.CurrentImage)
                msgbox('choose an image First!')
                return;
            end
            
            h = findobj(app.UIFigure, 'Type', 'uibutton');
            set(h, 'Enable', 'off');
            app.colormapDropDown.Enable = 'off';
            app.edgethreshholdEditField.Enable = 'off';
            app.smoothingfactorEditField.Enable = 'off';

            app.finishCButton.Enable = "on";
            app.CropRegion = NaN;
            app.smoothAndFilter();
            app.ROI = drawrectangle('Parent',app.ImageDisplay);
        end

        % Button pushed function: finishCButton
        function finishCButtonPushed(app, event)
            h = findobj(app.UIFigure, 'Type', 'uibutton');
            set(h, 'Enable', 'on');
            app.colormapDropDown.Enable = 'on';
            app.edgethreshholdEditField.Enable = 'on';
            app.smoothingfactorEditField.Enable = 'on';
            
            app.finishCButton.Enable = "off";
            app.finishMButton.Enable = 'off';
            
            app.CropRegion = app.ROI.Position;
            app.smoothAndFilter();
        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            if isempty(app.CurrentImage)
                text(0,0.5,"you suck!", 'FontSize',80)
                return;
            end

            h = findobj(app.UIFigure, 'Type', 'uibutton');
            set(h, 'Enable', 'off');
            app.colormapDropDown.Enable = 'off';
            app.edgethreshholdEditField.Enable = 'off';
            app.smoothingfactorEditField.Enable = 'off';

            app.finishMButton.Enable = "on";
            app.ROI = drawrectangle('Parent',app.ImageDisplay);
        end

        % Button pushed function: finishMButton
        function finishMButtonPushed(app, event)
            h = findobj(app.UIFigure, 'Type', 'uibutton');
            set(h, 'Enable', 'on');
            app.colormapDropDown.Enable = 'on';
            app.edgethreshholdEditField.Enable = 'on';
            app.smoothingfactorEditField.Enable = 'on';

            app.finishCButton.Enable = "off";
            app.finishMButton.Enable = 'off';
            app.CurrentImage(ceil(app.ROI.Position(2)):floor(app.ROI.Position(2)+app.ROI.Position(4)), ...
                ceil(app.ROI.Position(1)):floor(app.ROI.Position(1)+app.ROI.Position(3))) = 0;
            imshow(app.CurrentImage, 'Parent', app.ImageDisplay);
        end

        % Button pushed function: extractDataButton
        function extractDataButtonPushed(app, event)
            if isempty(app.CurrentImage)
                msgbox('Did you actually think it through before you pressed that button? like really?')
                return;
            end
            app.Data = [];
            for i = 1:size(app.CurrentImage, 2)
                f = find(app.CurrentImage(:,i), 1, 'last');
                if ~isempty(f)
                    app.Data = [app.Data; i f];
                end
            end
            plot(app.Data(:,1),app.Data(:,2), 'k.', 'Parent',app.UIAxes);

            app.ProcessedData = [];
            app.Rsquare.Text = '';

            app.ProcessDataButton.Enable = 'on';
        end

        % Button pushed function: saveDataButton
        function saveDataButtonPushed(app, event)
            if strcmp('no location set',app.saveLocation.Text)
                msgbox('no save location set. set location, THEN try to save.')
                return;
            elseif isempty(app.Data)
                msgbox('no data to save. Think, before you do.')
                return;
            elseif isempty(app.ProcessedData)
                 s = split(char(app.FilesToProcess.Items(app.ImageItem)), '.');
                 writematrix(app.Data, [app.saveLocation.Text '\' char(s(1)) '_raw.txt'],"Delimiter",'\t');
                 app.Rsquare.Text = [app.Rsquare.Text '      SAVED RAW'];
            else
                s = split(char(app.FilesToProcess.Items(app.ImageItem)), '.');
                writetable(app.ProcessedData, [app.saveLocation.Text '\' char(s(1)) '_processed.txt'],"Delimiter",'\t');
                app.Rsquare.Text = [app.Rsquare.Text '      SAVED PROCESSED'];
            end
        end

        % Button pushed function: ProcessDataButton
        function ProcessDataButtonPushed(app, event)
            if (app.AEditField.Value == 0 ||...
                app.BEditField.Value == 0 || ...
                app.wEditField.Value == 0 || ...
                app.mEditField.Value == 0 || ...
                app.convEditField.Value == 0)

                msgbox("you must enter values into the relevent Fields to use this function, idiot! :-p)")
                return;
            end    
            % prepare Data
            xPixels = app.Data(:,1)';
            yPixels = app.Data(:,2)';

            % convert from width pixels to height and cm and create error
            % propagation
            xHeight = xPixels.*app.mEditField.Value*app.convEditField.Value;
            xHeightError = xPixels.*sqrt((app.convEditField.Value*app.mErrEditField.Value)^2 ...
                + (app.mEditField.Value*app.cErrEditField.Value)^2);
            
            yHeight = yPixels.*app.convEditField.Value;
            yHeightError = yPixels.*app.cErrEditField.Value;

            % calculate y relative height, propagate error.
            yRelHeight = yHeight - xHeight;
            yRelHeightError = sqrt(xHeightError.^2 + yHeightError.^2);

            % tranform geometricaly to gradiant on surface.
            yFinalGard = yRelHeight./(app.wEditField.Value*app.BEditField.Value);
            yFinalGardError = sqrt(2*(yRelHeight.^2)*(app.ErrEditField.Value^2)./((app.wEditField.Value*app.BEditField.Value).^2) ...
                + (yRelHeightError.^2)./(app.wEditField.Value*app.BEditField.Value));

            xFinal = xHeight.*(app.AEditField.Value/(app.AEditField.Value + app.BEditField.Value));
            xFinalError = sqrt((xHeightError.*(app.AEditField.Value/(app.AEditField.Value + app.BEditField.Value))).^2 + ...
                2*(app.ErrEditField.Value.*xHeight.*(app.AEditField.Value/(app.AEditField.Value + app.BEditField.Value)^2)).^2 + ...
                (xHeight.*(app.ErrEditField.Value/(app.AEditField.Value + app.BEditField.Value))).^2);
            
            % bring y to a floor value - mostly to aid fit
            p = polyfit(xFinal(1:15), yFinalGard(1:15),1);
            yFinalGard = yFinalGard - p(2);

            % bring x so y max is at zero
            [M I] = max(yFinalGard);
            xFinal = xFinal - xFinal(I(1));

            % put in table
            app.ProcessedData = table(xFinal', yFinalGard', xFinalError', yFinalGardError', 'VariableNames',["x", "y", "xErr", "yErr"]);

            %graph plot
            idx = isfinite(xFinal) & isfinite(yFinalGard);
            [f g] = fit(xFinal(idx)', yFinalGard(idx)', fittype('gauss1'));
            errorbar(app.UIAxes, xFinal, yFinalGard, yFinalGardError, yFinalGardError, ...
                xFinalError, xFinalError, "bo", "MarkerFaceColor",[0.9 0 0]);
            %{
            hold(app.UIAxes, "on");
            plot(f, 'Parent',app.UIAxes);
            hold(app.UIAxes, 'off');
            %}

            cof = confint(f);
            app.Rsquare.Text = ['Rsquare = ' sprintf('%.6f',g.rsquare) '    sigma = '...
                sprintf('%.6f',f.c1) '±' sprintf('%.6f',(cof(2,3)-cof(1,3))/2)];

        end

        % Button pushed function: SetsavelocationButton
        function SetsavelocationButtonPushed(app, event)
            app.UIFigure.Visible = 'off';
            app.saveLocation.Text = uigetdir();
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: getfinalgraphsButton
        function getfinalgraphsButtonPushed(app, event)
            txtfiles = dir([app.saveLocation.Text '\*_processed.txt']);
            m = []
            hold on;
            for i = 1: length(txtfiles)
                currentMatrix = readmatrix([app.saveLocation.Text '\' txtfiles(i).name]);
                [f g] = fit(currentMatrix(:,1), currentMatrix(:,2), fittype('gauss1'));
                temp = diff(confint(f))/2;
            
                stdl = split(string(txtfiles(i).name), '_');
                m(i,1) = str2double(stdl(3))*3600 + str2double(stdl(4))*60 + str2double(stdl(5));
                m(i,2) = round(f.c1/sqrt(2), ceil(-log10(temp(3)/sqrt(2))));    
                m(i,3) = round(temp(3)/sqrt(2), ceil(-log10(temp(3)/sqrt(2))));
                m(i,4) = round(f.b1, ceil(-log10(temp(2))));
                m(i,5) = round(temp(2), ceil(-log10(temp(2))));
                m(i,6) = round(f.c1, ceil(-log10(temp(1))));
                m(i,7) = round(temp(1), ceil(-log10(temp(1))));
                m(i,8) = g.rsquare;
            
                plot(f);
                plot(currentMatrix(:,1),currentMatrix(:,2), '.');
            end
            m(:,1) = m(:,1) - min(m(:,1));
            hold off
            
            figure;
            [f g] = fit(m(:,1), m(:,2).^2, fittype('poly1'));
            errorbar(m(:,1),m(:,2).^2,2*m(:,3),2*m(:,3), '.');
            hold on
            plot(f);
            NW = [min(xlim) max(ylim)]+[diff(xlim) -diff(ylim)]*0.05;
            err = diff(confint(f))./2;
            text(NW(1),NW(2),['f(x)=p1*x+p2' newline...
                'p1 = ' num2str(f.p1) ' ± ' num2str(err(1)) newline...
                'p2 = ' num2str(f.p2) ' ± ' num2str(err(2)) newline...
                'Rsquare = ' num2str(g.rsquare)]);
            hold off
            
            writetable(array2table(m, 'VariableNames',{'time', 'standard deviation', 'standard deviation Error', ...
                'mean', 'mean Error', 'amplitude', 'amplitude Error', 'rsquared'}), [app.saveLocation.Text '\data.xlsx']);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.FilesToProcess.Items = {};
            app.FilesToProcess.ItemsData = {'a'};

            app.saveLocation.Text = 'no location set';

            cla(app.ImageDisplay);
            cla(app.UIAxes);

            app.Data = [];
            app.ProcessedData = [];
        end

        % Value changed function: initialthresholdSpinner
        function initialthresholdSpinnerValueChanged(app, event)
            app.smoothAndFilter();
        end

        % Button pushed function: ExtractslopeButton
        function ExtractslopeButtonPushed(app, event)
            if isempty(app.Data)
                msbox('first extract a data set. preferably the linear one.')
                return;
            end

            [f g] = fit(app.Data(:,1),app.Data(:,2), fittype('poly1'));
            
            s = diff(confint(f))/2;
            
            app.mEditField.Value = round(f.p1, ceil(-log10(s(1))));
            app.mErrEditField.Value = round(s(1), ceil(-log10(s(1))));

            x = [min(app.Data(:,1)):max(app.Data(:,1))];
            y = f.p1.*x + f.p2;
            
            hold(app.UIAxes, "on");
            plot(app.UIAxes, x, y, 'r-');
            hold(app.UIAxes, "off");

            app.Rsquare.Text = ['Rsquare = ' num2str(g.rsquare)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ImageDisplay = uiaxes(app.GridLayout3);
            app.ImageDisplay.Visible = 'off';
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'graph results')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Rsquare = uilabel(app.GridLayout3);
            app.Rsquare.Text = '';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.FilesToProcess = uilistbox(app.GridLayout7);
            app.FilesToProcess.Items = {};
            app.FilesToProcess.ItemsData = {'a'};
            app.FilesToProcess.DoubleClickedFcn = createCallbackFcn(app, @FilesToProcessDoubleClicked, true);
            app.FilesToProcess.Value = {};
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.AEditFieldLabel = uilabel(app.GridLayout8);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.GridLayout8, 'numeric');
            app.conversionparameterscmpxLabel = uilabel(app.GridLayout8);
            app.conversionparameterscmpxLabel.Text = 'conversion parameters (cm/px)';
            app.mEditFieldLabel = uilabel(app.GridLayout8);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.GridLayout8, 'numeric');
            app.BEditField_2Label = uilabel(app.GridLayout8);
            app.BEditField_2Label.Text = 'B';
            app.BEditField = uieditfield(app.GridLayout8, 'numeric');
            app.mErrEditFieldLabel = uilabel(app.GridLayout8);
            app.mErrEditFieldLabel.Text = 'mErr';
            app.mErrEditField = uieditfield(app.GridLayout8, 'numeric');
            app.wEditFieldLabel = uilabel(app.GridLayout8);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.GridLayout8, 'numeric');
            app.convEditFieldLabel = uilabel(app.GridLayout8);
            app.convEditFieldLabel.Text = 'conv';
            app.convEditField = uieditfield(app.GridLayout8, 'numeric');
            app.ErrEditFieldLabel = uilabel(app.GridLayout8);
            app.ErrEditFieldLabel.Text = 'Err';
            app.ErrEditField = uieditfield(app.GridLayout8, 'numeric');
            app.cErrEditFieldLabel = uilabel(app.GridLayout8);
            app.cErrEditFieldLabel.Text = 'cErr';
            app.cErrEditField = uieditfield(app.GridLayout8, 'numeric');
            app.ClearButton = uibutton(app.GridLayout7, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ControlTab = uitab(app.TabGroup);
            app.ControlTab.Title = 'Control';
            app.GridLayout2 = uigridlayout(app.ControlTab);
            app.getfinalgraphsButton = uibutton(app.GridLayout2, 'push');
            app.getfinalgraphsButton.ButtonPushedFcn = createCallbackFcn(app, @getfinalgraphsButtonPushed, true);
            app.getfinalgraphsButton.Text = 'get final graphs';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.MaskROILabel = uilabel(app.GridLayout6);
            app.MaskROILabel.Text = 'Mask ROI';
            app.MaskButton = uibutton(app.GridLayout6, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Text = 'Mask';
            app.finishMButton = uibutton(app.GridLayout6, 'push');
            app.finishMButton.ButtonPushedFcn = createCallbackFcn(app, @finishMButtonPushed, true);
            app.finishMButton.Enable = 'off';
            app.finishMButton.Text = 'finish M';
            app.extractDataButton = uibutton(app.GridLayout2, 'push');
            app.extractDataButton.ButtonPushedFcn = createCallbackFcn(app, @extractDataButtonPushed, true);
            app.extractDataButton.Text = 'extract Data';
            app.saveDataButton = uibutton(app.GridLayout2, 'push');
            app.saveDataButton.ButtonPushedFcn = createCallbackFcn(app, @saveDataButtonPushed, true);
            app.saveDataButton.Text = 'save Data';
            app.GridLayout10 = uigridlayout(app.GridLayout2);
            app.saveLocation = uilabel(app.GridLayout10);
            app.saveLocation.Text = 'no location set';
            app.SetsavelocationButton = uibutton(app.GridLayout10, 'push');
            app.SetsavelocationButton.ButtonPushedFcn = createCallbackFcn(app, @SetsavelocationButtonPushed, true);
            app.SetsavelocationButton.Text = 'Set save location';
            app.loadimagesButton = uibutton(app.GridLayout2, 'push');
            app.loadimagesButton.ButtonPushedFcn = createCallbackFcn(app, @loadimagesButtonPushed, true);
            app.loadimagesButton.Text = 'load images';
            app.ProcessDataButton = uibutton(app.GridLayout2, 'push');
            app.ProcessDataButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessDataButtonPushed, true);
            app.ProcessDataButton.Enable = 'off';
            app.ProcessDataButton.Text = 'Process Data';
            app.GridLayout11 = uigridlayout(app.GridLayout2);
            app.initialthresholdSpinnerLabel = uilabel(app.GridLayout11);
            app.initialthresholdSpinnerLabel.Text = 'initial threshold';
            app.initialthresholdSpinner = uispinner(app.GridLayout11);
            app.initialthresholdSpinner.Limits = [0 255];
            app.initialthresholdSpinner.RoundFractionalValues = 'on';
            app.initialthresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @initialthresholdSpinnerValueChanged, true);
            app.initialthresholdSpinner.Value = 180;
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout9 = uigridlayout(app.SettingsTab);
            app.GridLayout4 = uigridlayout(app.GridLayout9);
            app.smoothingfactorEditFieldLabel = uilabel(app.GridLayout4);
            app.smoothingfactorEditFieldLabel.Text = 'smoothing factor';
            app.smoothingfactorEditField = uieditfield(app.GridLayout4, 'numeric');
            app.smoothingfactorEditField.Limits = [0 15];
            app.smoothingfactorEditField.RoundFractionalValues = 'on';
            app.smoothingfactorEditField.ValueChangedFcn = createCallbackFcn(app, @smoothingfactorEditFieldValueChanged, true);
            app.smoothingfactorEditField.Value = 3;
            app.edgethreshholdEditFieldLabel = uilabel(app.GridLayout4);
            app.edgethreshholdEditFieldLabel.Text = 'edge threshhold';
            app.edgethreshholdEditField = uieditfield(app.GridLayout4, 'numeric');
            app.edgethreshholdEditField.Limits = [0 255];
            app.edgethreshholdEditField.RoundFractionalValues = 'on';
            app.edgethreshholdEditField.ValueChangedFcn = createCallbackFcn(app, @edgethreshholdEditFieldValueChanged, true);
            app.edgethreshholdEditField.Value = 100;
            app.colormapDropDownLabel = uilabel(app.GridLayout4);
            app.colormapDropDownLabel.Text = 'color map';
            app.colormapDropDown = uidropdown(app.GridLayout4);
            app.colormapDropDown.Items = {'R', 'G', 'B', 'WB'};
            app.colormapDropDown.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownValueChanged, true);
            app.colormapDropDown.Value = 'G';
            app.ExtractslopeButton = uibutton(app.GridLayout9, 'push');
            app.ExtractslopeButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractslopeButtonPushed, true);
            app.ExtractslopeButton.Text = 'Extract slope';
            app.GridLayout5 = uigridlayout(app.GridLayout9);
            app.CropButton = uibutton(app.GridLayout5, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.finishCButton = uibutton(app.GridLayout5, 'push');
            app.finishCButton.ButtonPushedFcn = createCallbackFcn(app, @finishCButtonPushed, true);
            app.finishCButton.Enable = 'off';
            app.finishCButton.Text = 'finish C';
            app.CroptoROILabel = uilabel(app.GridLayout5);
            app.CroptoROILabel.Text = 'Crop to ROI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = diffusion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end